classdef PhiModFinder190122 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        MassAmount                    matlab.ui.control.NumericEditField
        GoButton                      matlab.ui.control.Button
        lblMass                       matlab.ui.control.Label
        UIAxes_2                      matlab.ui.control.UIAxes
        AxisLimitEditFieldLabel       matlab.ui.control.Label
        AxisLimit                     matlab.ui.control.NumericEditField
        IntersectionatEditFieldLabel  matlab.ui.control.Label
        IntersectionatEditField       matlab.ui.control.NumericEditField
        PhiModEditFieldLabel          matlab.ui.control.Label
        PhiModEditField               matlab.ui.control.NumericEditField
        DLabel                        matlab.ui.control.Label
        DField                        matlab.ui.control.NumericEditField
        BPhotField                    matlab.ui.control.NumericEditField
        B_photLabel                   matlab.ui.control.Label
        aField                        matlab.ui.control.NumericEditField
        aLabel                        matlab.ui.control.Label
    end

    methods (Access = private)

        % Callback function: GoButton, MassAmount
        function GoButtonPushed(app, event)
            %User Specfied Values
            m = app.MassAmount.Value;
            D=app.DField.Value;
            a=app.aField.Value;
            BPhot = app.BPhotField.Value;
            U = app.AxisLimit.Value; %UpperAxisBound
             
            %Global Constants 
            LB = a/2 + 0.1; %This lowerbound is for the chebfuns. Mostly to prevent the occurance of a 1/0 in I_evol2.
            M = 1.98e33;
            G = 6.674e-8;
            R_s = 6.958e10;
            I_Max=1; %Because Jack said so
            hmax=1; %Gives value of Max(I_eq) and its location, which should be hmax.
            
            %Calcuable Params
            phi=(pi*D*BPhot)/2; %Phi_0
            
            %%Various functions we need for the rootfind
            h = chebfun('h', [LB U]); %This is indeed just a linear function, I know. Sometimes Chebfun likes it if you handhold it.
            
            BExt = chebfun(@(x) (2*phi)./(D*pi*( 1+ h(x).^2)), [LB U] );
            
            g= chebfun(@(x) (G*M)./((R_s + D*h(x)).^2), [LB U]);
            
            I_eq = chebfun(@(x) ((pi*h(x)*D)/(2*phi)).*(BExt(x) + ((BExt(x).^2 + ((4.*m.*g(x))./(D.*h(x))))).^(0.5)), [LB U]);
            
            F = chebfun(@(x) 2*I_Max * log((2/a)*hmax) - 2*atan(hmax - a) , [LB U] ); 
            
            I_evol2 = chebfun2(@(x,y) ((F(x)./y + (2.*atan(h(x) - a)))./(2.*log((2/a).*h(x)))), [LB U LB U]);
            
            %% Rootfind
            %This turned out to be less trivial than one would expect, but it is still fine.
            %Draw some pictures.
            
            Distance = chebfun2(@(x,y) I_eq(x)-I_evol2(x,y), [LB U LB U]);
            r=roots(Distance);
            k=imag(r);
            [PhiMod b] = min(k);
            Intersection = real(r(b));
            
            %Return the roots
            app.IntersectionatEditField.Value=Intersection;
            app.PhiModEditField.Value=PhiMod;
            
            %Time for plots!
            %I_evol = chebfun(@(x) ((F(x)./PhiMod + (2.*atan(h(x) - a)))./(2.*log((2/a).*h(x)))), [LB U]);
            
            I_evol = chebfun(@(x) ((F(x)./PhiMod + (2.*atan(h(x) - a)))./(2.*log((2/a).*h(x)))), [LB U]);
            
            
            plot(app.UIAxes,r(-1:0.01:1),'r');
            hold(app.UIAxes,'on');
            plot(app.UIAxes,r(b),'x-k');
            hold(app.UIAxes,'off');
            
            plot(app.UIAxes_2,h(LB:0.01:U),I_eq(LB:0.01:U),'-r')
            hold(app.UIAxes_2,'on');
            plot(app.UIAxes_2,h(LB:0.01:U),I_evol(LB:0.01:U),'-b')
            plot(app.UIAxes_2,Intersection,I_evol(real(r(b))),'x-k');
            hold(app.UIAxes_2,'off');


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Values of intersection for a given mass.')
            xlabel(app.UIAxes, 'h')
            ylabel(app.UIAxes, '\phi_{mod}')
            app.MassAmount = uieditfield(app.UIFigure, 'numeric');
            app.MassAmount.Limits = [0 Inf];
            app.MassAmount.ValueDisplayFormat = '%8.f';
            app.MassAmount.ValueChangedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.MassAmount.Value = 10000;
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.lblMass = uilabel(app.UIFigure);
            app.lblMass.Text = 'Mass';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Curves I_{eq} and I_{evol}')
            xlabel(app.UIAxes_2, 'h')
            ylabel(app.UIAxes_2, 'I')
            app.AxisLimitEditFieldLabel = uilabel(app.UIFigure);
            app.AxisLimitEditFieldLabel.Text = 'Axis Limit';
            app.AxisLimit = uieditfield(app.UIFigure, 'numeric');
            app.AxisLimit.Limits = [2 Inf];
            app.AxisLimit.Value = 2;
            app.IntersectionatEditFieldLabel = uilabel(app.UIFigure);
            app.IntersectionatEditFieldLabel.Text = 'Intersection at';
            app.IntersectionatEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhiModEditFieldLabel = uilabel(app.UIFigure);
            app.PhiModEditFieldLabel.Text = 'Phi Mod';
            app.PhiModEditField = uieditfield(app.UIFigure, 'numeric');
            app.DLabel = uilabel(app.UIFigure);
            app.DLabel.Text = 'D';
            app.DField = uieditfield(app.UIFigure, 'numeric');
            app.DField.Value = 6500000000;
            app.BPhotField = uieditfield(app.UIFigure, 'numeric');
            app.BPhotField.Value = 4;
            app.B_photLabel = uilabel(app.UIFigure);
            app.B_photLabel.Text = 'B_phot';
            app.aField = uieditfield(app.UIFigure, 'numeric');
            app.aField.Value = 0.1;
            app.aLabel = uilabel(app.UIFigure);
            app.aLabel.Text = 'a';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PhiModFinder190122

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end